classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_4              matlab.ui.container.Menu
        Menu_5              matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        Menu_6              matlab.ui.container.Menu
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Panel_10            matlab.ui.container.Panel
        GridLayout_9        matlab.ui.container.GridLayout
        Button_37           matlab.ui.control.Button
        Button_38           matlab.ui.control.Button
        Button_39           matlab.ui.control.Button
        Button_40           matlab.ui.control.Button
        TabGroup2           matlab.ui.container.TabGroup
        Tab_6               matlab.ui.container.Tab
        Panel_14            matlab.ui.container.Panel
        GridLayout_13       matlab.ui.container.GridLayout
        Button_41           matlab.ui.control.Button
        Button_42           matlab.ui.control.Button
        Button_43           matlab.ui.control.Button
        EditField           matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        EditField_2         matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        Tab_8               matlab.ui.container.Tab
        Panel_15            matlab.ui.container.Panel
        GridLayout_14       matlab.ui.container.GridLayout
        SobelButton         matlab.ui.control.Button
        LaplaceButton       matlab.ui.control.Button
        Panel_16            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        Button_44           matlab.ui.control.Button
        Button_45           matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        Label_4             matlab.ui.control.Label
        EditField_5         matlab.ui.control.NumericEditField
        Tab_11              matlab.ui.container.Tab
        Panel_20            matlab.ui.container.Panel
        GridLayout7         matlab.ui.container.GridLayout
        Button_51           matlab.ui.control.Button
        Button_52           matlab.ui.control.Button
        Button_53           matlab.ui.control.Button
        Tab_2               matlab.ui.container.Tab
        TabGroup3           matlab.ui.container.TabGroup
        Tab_9               matlab.ui.container.Tab
        Panel_13            matlab.ui.container.Panel
        GridLayout_12       matlab.ui.container.GridLayout
        RobertsButton       matlab.ui.control.Button
        CannyButton         matlab.ui.control.Button
        PrewittButton       matlab.ui.control.Button
        LOGButton           matlab.ui.control.Button
        Panel_17            matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        HarrisButton        matlab.ui.control.Button
        FASTButton          matlab.ui.control.Button
        Spinner             matlab.ui.control.Spinner
        Label_5             matlab.ui.control.Label
        Tab_10              matlab.ui.container.Tab
        Panel_7             matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        GridLayout_6        matlab.ui.container.GridLayout
        Button_25           matlab.ui.control.Button
        Button_26           matlab.ui.control.Button
        Button_27           matlab.ui.control.Button
        Spinner_2           matlab.ui.control.Spinner
        Label_6             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        Spinner_3           matlab.ui.control.Spinner
        Tab_3               matlab.ui.container.Tab
        Panel_8             matlab.ui.container.Panel
        GridLayout_7        matlab.ui.container.GridLayout
        Button_29           matlab.ui.control.Button
        Button_30           matlab.ui.control.Button
        Button_31           matlab.ui.control.Button
        Button_32           matlab.ui.control.Button
        Panel_19            matlab.ui.container.Panel
        GridLayout_15       matlab.ui.container.GridLayout
        Button_49           matlab.ui.control.Button
        Button_50           matlab.ui.control.Button
        Button_47           matlab.ui.control.Button
        Button_48           matlab.ui.control.Button
        Label_8             matlab.ui.control.Label
        Slider_2            matlab.ui.control.Slider
        Tab_5               matlab.ui.container.Tab
        Panel_5             matlab.ui.container.Panel
        GridLayout_5        matlab.ui.container.GridLayout
        Button_23           matlab.ui.control.Button
        Button_24           matlab.ui.control.Button
        Panel_6             matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        Button_10           matlab.ui.control.Button
        Button_11           matlab.ui.control.Button
        Button_12           matlab.ui.control.Button
        Button_13           matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Img % Description
        OutImg % Description
        fileName % Description
        filePath % Description
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            msgbox("这是一个简单的基于matlab的图像处理系统","关于");
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [app.fileName,app.filePath] = uigetfile(["*.jpg";"*.bmp";"*.tif";"*.gif"],"打开文件");
            if isequal(app.fileName,0) || isequal(app.filePath,0)
                errordlg("没有选中文件，错误");
                return;
            end
            app.Img = imread(fullfile(app.filePath,app.fileName));
            imshow(app.Img,'Parent',app.UIAxes,"DisplayRange",[]);
            h = imhist(app.Img);
            bar(app.UIAxes2,h);
            cla(app.UIAxes_2);
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if isempty(app.Img) && (value ~= "无")
                errordlg("没有原图像","错误","@@");
                app.ListBox.Value = "无";
                return;
            end
            switch value
                case "无"
                    cla(app.UIAxes_2);
                case "灰度图"
                    if numel(size(app.Img)) > 2
                        app.OutImg = rgb2gray(app.Img);
                    else
                        app.OutImg = app.Img;
                    end
                    imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
                    h = imhist(app.Outing);
                    bar(app.UIAxes3,h);
                case "二值化"
                    %转化为灰度图
                    if numel(size(app.Img)) > 2
                        app.OutImg = rgb2gray(app.Img);
                    else
                        app.OutImg = app.Img;
                    end
                    if class(app.OutImg) == "logical"
                        return;
                    end
                    thresh = graythresh(app.OutImg); %自动确定二值化阈值
                    app.OutImg = imbinarize(app.OutImg,thresh);
                    imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
                    h = imhist(app.Outing);
                    bar(app.UIAxes3,h);
                case "水平翻转"
                    app.OutImg = fliplr(app.Img);
                    imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
                    h = imhist(app.Outing);
                    bar(app.UIAxes3,h);
                case "垂直翻转"
                    app.OutImg = flipud(app.OutImg);
                    imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
                    h = imhist(app.Outing);
                    bar(app.UIAxes3,h);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 检查报错
            if isempty(app.OutImg)
                errordlg("没有处理后的图像,请重新操作","一个小错误","!");
                return ;
            end
            app.Img = app.OutImg;
            imshow(app.Img,'Parent',app.UIAxes,"DisplayRange",[]);
            h = imhist(app.Img);
            bar(app.UIAxes2,h);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.delete;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Img = [];
            app.OutImg = [];
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes_2);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            switch selectedButton.Text
                case "椒盐噪声"
                    app.OutImg = imnoise(app.Img,'salt & pepper',0.05);
                    imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
                case "高斯噪声"
                    app.OutImg = imnoise(app.Img,'gaussian',0,0.01);
                    imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
                case "泊松噪声"
                    app.OutImg = imnoise(app.Img,'poisson');
                    imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
                case "乘性噪声"
                    app.OutImg = imnoise(app.Img,'speckle');
                    imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            end
        end

        % Callback function
        function Button_8Pushed(app, event)
            %             app.Button.ta
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            else
                app.OutImg = app.Img;
            end
            app.Label_2.Text = '灰度化图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '灰度化直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            else
                app.OutImg = app.Img;
            end
            if class(app.OutImg) == "logical"
                return;
            end
            thresh = graythresh(app.OutImg); %自动确定二值化阈值
            app.OutImg = imbinarize(app.OutImg,thresh);
            app.Label_2.Text = '二值化图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '二值化直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = fliplr(app.Img);
            app.Label_2.Text = '水平翻转图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '水平翻转直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = flipud(app.OutImg);
            app.Label_2.Text = '垂直翻转图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '垂直翻转直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_37
        function Button_16Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if class(app.Img) == "logical"
                errordlg("二值化图像不能加噪","错误","@@");
                return;
            end
            app.OutImg = imnoise(app.Img,'salt & pepper',0.05);
            app.Label_2.Text = '加入椒盐噪声图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '加入椒盐噪声后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_38
        function Button_17Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if class(app.Img) == "logical"
                errordlg("二值化图像不能加噪","错误","@@");
                return;
            end
            app.OutImg = imnoise(app.Img,'gaussian',0,0.01);
            app.Label_2.Text = '加入高斯噪声图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '加入高斯噪声后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_39
        function Button_18Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if class(app.Img) == "logical"
                errordlg("二值化图像不能加噪","错误","@@");
                return;
            end
            app.OutImg = imnoise(app.Img,'speckle');
            app.Label_2.Text = '加入乘性噪声图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '加入乘性噪声后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_40
        function Button_19Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if class(app.Img) == "logical"
                errordlg("二值化图像不能加噪","错误","@@");
                return;
            end
            app.OutImg = imnoise(app.Img,'poisson');
            app.Label_2.Text = '加入泊松噪声图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '加入泊松噪声后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            value = app.EditField.Value;
            h = fspecial('average',[value,value]);
            app.OutImg = imfilter(app.Img,h);
            app.Label_2.Text = '均值滤波后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '均值滤波后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            value = app.EditField_2.Value;
            if numel(size(app.Img)) > 2
                redChannel = medfilt2(app.Img(:,:,1), [value, value]);
                greenChannel = medfilt2(app.Img(:,:,2), [value, value]);
                blueChannel = medfilt2(app.Img(:,:,3), [value, value]);
                app.OutImg = cat(3, redChannel, greenChannel, blueChannel);
                imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            else
                app.OutImg = medfilt2(app.Img,[value,value]);
                imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            end
            app.Label_2.Text = '中值滤波后图像';
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '中值滤波后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            %标准差
            cha = app.EditField_4.Value;
            value = app.EditField_3.Value;
            h = fspecial('gaussian',[value,value],cha);
            app.OutImg = imfilter(app.Img,h);
            app.Label_2.Text = '高斯滤波后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '高斯滤波后直方图';
            bar(app.UIAxes3,h);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            if isempty(app.OutImg)
                errordlg("没有原图像","错误","@@");
                return;
            end
            exportgraphics(app.UIAxes_2,fullfile(app.filePath,app.fileName));
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            % 计算sobel算子
            sobel_x = [-1 0 1; -2 0 2; -1 0 1];
            sobel_y = [-1 -2 -1; 0 0 0; 1 2 1];
            %判断是否为rgb图像
            if numel(size(app.Img)) > 2
                red_channel = app.Img(:,:,1);
                green_channel = app.Img(:,:,2);
                blue_channel = app.Img(:,:,3);
                
                edges_x_red = imfilter(double(red_channel), sobel_x);
                edges_y_red = imfilter(double(red_channel), sobel_y);
                
                edges_x_green = imfilter(double(green_channel), sobel_x);
                edges_y_green = imfilter(double(green_channel), sobel_y);
                
                edges_x_blue = imfilter(double(blue_channel), sobel_x);
                edges_y_blue = imfilter(double(blue_channel), sobel_y);
                
                % 合并锐化后的通道以生成彩色图像
                app.OutImg = cat(3, uint8(edges_x_red + edges_y_red), ...
                    uint8(edges_x_green + edges_y_green), ...
                    uint8(edges_x_blue + edges_y_blue));
                app.OutImg = app.Img + app.OutImg;
            else
                %此时为灰度图像
                edges_x = imfilter(double(app.Img), sobel_x);
                edges_y = imfilter(double(app.Img), sobel_y);
                
                app.OutImg = double(app.Img) + edges_x + edges_y;
            end
            app.Label_2.Text = 'sobel锐化后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Sobel锐化后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: LaplaceButton
        function LaplaceButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            laplacian_filter = [0 -1 0; -1 4 -1; 0 -1 0];
            if numel(size(app.Img)) > 2
                red_channel = app.Img(:, :, 1);
                green_channel = app.Img(:, :, 2);
                blue_channel = app.Img(:, :, 3);
                
                sharpened_red = imfilter(double(red_channel), laplacian_filter);
                sharpened_green = imfilter(double(green_channel), laplacian_filter);
                sharpened_blue = imfilter(double(blue_channel), laplacian_filter);
                app.OutImg = cat(3, uint8(sharpened_red), uint8(sharpened_green), uint8(sharpened_blue));
                app.OutImg = app.OutImg + app.Img;
            else
                sharpened_image = imfilter(double(app.Img), laplacian_filter);
                app.OutImg = double(app.Img) + 2 * sharpened_image;
            end
            app.Label_2.Text = 'Laplace锐化后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Laplace锐化后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                negative_red = 255 - app.Img(:,:,1);
                negative_green = 255 - app.Img(:,:,2);
                negative_blue = 255 - app.Img(:,:,3);
                app.OutImg = cat(3, negative_red, negative_green, negative_blue);
            else
                app.OutImg = 255 - app.Img;
            end
            app.Label_2.Text = '负片变换后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '反色后直方图';
            bar(app.UIAxes3,h);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = changingValue;
        end

        % Button pushed function: Button_45
        function Button_45Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            gamma = app.EditField_5.Value;
            app.OutImg = imadjust(app.Img, [], [], gamma);
            app.Label_2.Text = '伽马变换后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = '伽马变换后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: HarrisButton
        function HarrisButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            value = app.Spinner.Value;
            if numel(size(app.Img)) > 2
                cornerPoints_R = detectHarrisFeatures(app.Img(:,:,1));
                cornerPoints_G = detectHarrisFeatures(app.Img(:,:,2));
                cornerPoints_B = detectHarrisFeatures(app.Img(:,:,3));
                corners1 = [cornerPoints_R; cornerPoints_G; cornerPoints_B];
            else
                corners1 = detectHarrisFeatures(app.Img);
            end
            corners1 = corners1.selectStrongest(value);
            app.Label_2.Text = 'harris点检测图像';
            imshow(app.Img,'Parent',app.UIAxes_2,'DisplayRange',[]);
            hold(app.UIAxes_2,"on");
            % 假设 cornerPointsObj 是您的 cornerPoints 对象，axesObj 是您的 axes 对象
            scatter(app.UIAxes_2,corners1.Location(:, 1), corners1.Location(:, 2), 'g', 'filled');
            
        end

        % Button pushed function: FASTButton
        function FASTButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            value = app.Spinner.Value;
            if numel(size(app.Img)) > 2
                cornerPoints_R = detectFASTFeatures(app.Img(:,:,1));
                cornerPoints_G = detectFASTFeatures(app.Img(:,:,2));
                cornerPoints_B = detectFASTFeatures(app.Img(:,:,3));
                corners1 = [cornerPoints_R; cornerPoints_G; cornerPoints_B];
            else
                corners1 = detectHarrisFeatures(app.Img);
            end
            corners1 = corners1.selectStrongest(value);
            app.Label_2.Text = 'FAST点检测图像';
            imshow(app.Img,'Parent',app.UIAxes_2,'DisplayRange',[]);
            hold(app.UIAxes_2,"on");
            % 假设 cornerPointsObj 是您的 cornerPoints 对象，axesObj 是您的 axes 对象
            scatter(app.UIAxes_2,corners1.Location(:, 1), corners1.Location(:, 2), 'g', 'filled');
        end

        % Callback function
        function Button_46Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            h = app.Img;
            if class(app.Img) ~= "logical"
                if numel(size(h)) > 2
                    h = rgb2gray(h);
                end
                h = imbinarize(h,graythresh(h));
            end
            [H, T, R] = hough(h);
            
            % 寻找霍夫变换的峰值，这将是检测到的直线
            P = houghpeaks(H, 5);  % 这里选择了 5 个峰值
            % 获取峰值对应的直线参数
            lines = houghlines(h, T, R, P);
            app.Label_2.Text = '霍夫线检测图像';
            imshow(app.Img,'Parent',app.UIAxes_2,'DisplayRange',[]);
            hold(app.UIAxes_2,"on");
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(app.UIAxes_2 ,xy(:,1), xy(:,2), 'LineWidth', 2, 'Color', 'g');
            end
            
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                red_channel = app.Img(:, :, 1);
                green_channel = app.Img(:, :, 2);
                blue_channel = app.Img(:, :, 3);
                
                roberts_h = [1 0; 0 -1];
                roberts_v = [0 1; -1 0];
                red_edges_h = conv2(double(red_channel), roberts_h, 'same');
                red_edges_v = conv2(double(red_channel), roberts_v, 'same');
                
                green_edges_h = conv2(double(green_channel), roberts_h, 'same');
                green_edges_v = conv2(double(green_channel), roberts_v, 'same');
                
                blue_edges_h = conv2(double(blue_channel), roberts_h, 'same');
                blue_edges_v = conv2(double(blue_channel), roberts_v, 'same');
                
                % 计算每个通道的边缘强度
                red_edge_magnitude = sqrt(red_edges_h.^2 + red_edges_v.^2);
                green_edge_magnitude = sqrt(green_edges_h.^2 + green_edges_v.^2);
                blue_edge_magnitude = sqrt(blue_edges_h.^2 + blue_edges_v.^2);
                
                % 合并通道，生成彩色边缘图像
                app.OutImg = cat(3, uint8(red_edge_magnitude), uint8(green_edge_magnitude), uint8(blue_edge_magnitude));
            else
                roberts_h = [1 0; 0 -1];
                roberts_v = [0 1; -1 0];
                
                % 使用卷积操作检测水平边缘
                horizontal_edges = conv2(double(app.Img), roberts_h, 'same');
                
                % 使用卷积操作检测垂直边缘
                vertical_edges = conv2(double(app.Img), roberts_v, 'same');
                
                % 计算边缘强度
                app.OutImg = sqrt(horizontal_edges.^2 + vertical_edges.^2);
            end
            app.Label_2.Text = 'Roberts检测图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Roberts检测直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            h = app.Img;
            roberts_h = [1 0; 0 -1];
            roberts_v = [0 1; -1 0];
            if numel(size(app.Img)) > 2
                h = rgb2gray(app.Img);
            end
            horizontal_edges = conv2(double(h), roberts_h, 'same');
            
            % 使用卷积操作检测垂直边缘
            vertical_edges = conv2(double(h), roberts_v, 'same');
            
            % 计算边缘强度
            app.OutImg = sqrt(horizontal_edges.^2 + vertical_edges.^2);
            app.Label_2.Text = 'Canny检测图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Canny检测后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            h = app.Img;
            prewitt_h = [-1 -1 -1; 0 0 0; 1 1 1];
            prewitt_v = [-1 0 1; -1 0 1; -1 0 1];
            if numel(size(app.Img)) > 2
                h = rgb2gray(app.Img);
            end
            % 使用imfilter函数应用卷积操作检测水平边缘
            horizontal_edges = imfilter(double(h), prewitt_h, 'conv', 'symmetric');
            
            % 使用imfilter函数应用卷积操作检测垂直边缘
            vertical_edges = imfilter(double(h), prewitt_v, 'conv', 'symmetric');
            
            % 计算边缘强度
            app.OutImg = sqrt(horizontal_edges.^2 + vertical_edges.^2);
            app.Label_2.Text = 'Prewitt检测图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Prewitt检测后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: LOGButton
        function LOGButtonPushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            h = app.Img;
            sigma = 1.5; % LOG算子的标准差
            kernel_size = 7; % 卷积核大小，通常为奇数
            log_kernel = fspecial('log', kernel_size, sigma);
            app.OutImg = imfilter(double(h), log_kernel, 'conv', 'symmetric');
            app.Label_2.Text = 'Log检测图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,"DisplayRange",[]);
            h = imhist(app.OutImg);
            app.UIAxes3.Title.String = 'Log检测后直方图';
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            %进行傅里叶变换
            fft_img = fft2(double(app.OutImg));
            % 将零频分量移到中心
            fft_img = fftshift(fft_img);
            app.OutImg = log(1 + abs(fft_img));
            app.Label_2.Text = '傅里叶变换图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[],"InitialMagnification",'fit');
        end

        % Button pushed function: Button_30
        function Button_30Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            fft_img = fft2(app.OutImg);
            % 对傅里叶变换结果进行逆变换
            ifft_img = ifft2(fft_img);
            app.OutImg = abs(ifft_img);
            app.Label_2.Text = '傅里叶逆变换图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            dct_image = dct2(double(app.OutImg));
            app.OutImg = log(abs(dct_image));
            app.Label_2.Text = '离散余弦变换图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            % 选择小波基函数和层数
            wavelet_name = 'haar'; % 你可以选择不同的小波基函数，如'db2'等
            num_levels = 3; % 定义小波变换的层数
            
            % 执行离散小波变换
            [C, S] = wavedec2(app.OutImg, num_levels, wavelet_name);
            % 反变换以可视化小波变换后的图像
            app.OutImg = uint8(waverec2(C, S, wavelet_name));
            app.Label_2.Text = '离散小波变换图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            app.UIAxes2.Title.String = '输入直方图统计';
            app.UIAxes3.Title.String = '输出直方图统计';
            H = imhist(app.Img);
            plot(H,'Parent',app.UIAxes2);
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            app.OutImg = histeq(app.OutImg);
            app.Label_2.Text = '直方图均值化';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '直方图均衡化';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = uint8(app.Img);
            if numel(size(app.Img)) > 2
                app.OutImg = uint8(rgb2gray(app.OutImg));
            end
            value = app.Spinner_2.Value;
            app.OutImg = imbinarize(uint8(app.OutImg),value / 255);
            app.Label_2.Text = '阈值分割图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '阈值分割直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            
            figure
            imshow(app.Img)
            [M,N]=size(app.Img);
            [y,x]=getpts; %单击取点后，按enter结束
            hFig = gcf;
            close(hFig);
            x1=round(x);
            y1=round(y);
            seed=app.OutImg(x1,y1); %获取中心像素灰度值
            
            J=zeros(M,N);
            J(x1,y1)=1;
            
            count=1; %待处理点个数
            threshold=0.15;
            while count>0
                count=0;
                for i=1:M %遍历整幅图像
                    for j=1:N
                        if J(i,j)==1 %点在“栈”内
                            if (i-1)>1&(i+1)<M&(j-1)>1&(j+1)<N %3*3邻域在图像范围内
                                for u=-1:1 %8-邻域生长
                                    for v=-1:1
                                        if J(i+u,j+v)==0&&abs(app.OutImg(i+u,j+v)-seed)<=threshold
                                            J(i+u,j+v)=1;
                                            count=count+1;  %记录此次新生长的点个数
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            app.OutImg = J;
            app.Label_2.Text = '区域生长图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '区域生长图像';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            app.OutImg = app.OutImg > app.Spinner_3.Value;
            labels = bwlabel(app.OutImg);
            
            % 4. 分析标签矩阵并分离相邻区域
            [row, col] = size(labels);
            neighborhood = [-1, 0; 1, 0; 0, -1; 0, 1]; % 上下左右四个相邻像素的偏移
            
            % 创建一个新标签以分离区域
            new_label = max(labels(:)) + 1;
            
            for r = 1:row
                for c = 1:col
                    current_label = labels(r, c);
                    if current_label == 0
                        continue; % 背景区域跳过
                    end
                    
                    % 检查当前像素的相邻像素
                    for i = 1:size(neighborhood, 1)
                        nr = r + neighborhood(i, 1);
                        nc = c + neighborhood(i, 2);
                        
                        if nr >= 1 && nr <= row && nc >= 1 && nc <= col
                            neighbor_label = labels(nr, nc);
                            
                            % 如果相邻区域与当前区域不同，创建新的标签
                            if neighbor_label > 0 && current_label ~= neighbor_label
                                labels(labels == neighbor_label) = new_label;
                            end
                        end
                    end
                end
            end
            
            % 5. 根据更新后的标签矩阵重新生成分离后的图像
            app.OutImg = label2rgb(labels);
            app.Label_2.Text = '区域分离图像';
            app.OutImg = rgb2gray(app.OutImg);
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '区域分离图像';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_49
        function Button_49Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            se = strel('disk',round(app.Slider_2.Value));
            app.OutImg = imerode(app.Img, se);
            app.Label_2.Text = '腐蚀运算后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '腐蚀运算后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_50
        function Button_50Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            se = strel('disk',round(app.Slider_2.Value));
            app.OutImg = imdilate(app.Img, se);
            app.Label_2.Text = '膨胀运算后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '膨胀运算后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_47
        function Button_47Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            se = strel('disk',round(app.Slider_2.Value));
            app.OutImg = imopen(app.Img, se);
            app.Label_2.Text = '开运算后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '开运算后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_48
        function Button_48Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            se = strel('disk',round(app.Slider_2.Value));
            app.OutImg = imclose(app.Img, se);
            app.Label_2.Text = '闭运算后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '闭运算后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            [filename, filepath] = uiputfile({'*.png', 'PNG Files (*.png)'; '*.jpg', 'JPEG Files (*.jpg)'; '*.pdf', 'PDF Files (*.pdf)'}, '另存为');
            if isequal(filename,0) || isequal(filepath,0)
                errordlg("没有选中文件，错误");
                return;
            end

            fullFilePath = fullfile(filepath, filename);

            exportgraphics(app.UIAxes,fullFilePath);

        end

        % Button pushed function: Button_51
        function Button_51Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            app.OutImg = app.Img;
            if numel(size(app.Img)) > 2
                app.OutImg = rgb2gray(app.Img);
            end
            %进行傅里叶变换
            fft_img = fft2(double(app.OutImg));
            % 将零频分量移到中心
            fft_img = fftshift(fft_img);
            app.OutImg = log(1 + abs(fft_img));
            app.Label_2.Text = '傅里叶变换图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[],"InitialMagnification",'fit');
        end

        % Button pushed function: Button_52
        function Button_52Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            grayImage = app.Img;
            if numel(size(app.Img)) > 2
                grayImage = rgb2gray(app.Img);
            end
            spectrum = fftshift(fft2(grayImage));
            % 使用高斯滤波平滑频谱
            sigma = 5; % 调整这个值以控制平滑程度
            [rows, cols] = size(spectrum);
            H = fspecial('gaussian', [rows, cols], sigma);
            smoothedSpectrum = spectrum .* H;
            % 恢复平滑后的图像
            smoothedImage = abs(ifft2(ifftshift(smoothedSpectrum)));
            app.OutImg = mat2gray(smoothedImage);
            app.Label_2.Text = '平滑后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '平滑后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
        end

        % Button pushed function: Button_53
        function Button_53Pushed(app, event)
            if isempty(app.Img)
                errordlg("没有原图像","错误","@@");
                return;
            end
            grayImage = app.Img;
            if numel(size(app.Img)) > 2
                grayImage = rgb2gray(app.Img);
            end
            spectrum = fftshift(fft2(grayImage));
            % 定义高频增强滤波器
            [rows, cols] = size(spectrum);
            centerRow = floor(rows/2) + 1;
            centerCol = floor(cols/2) + 1;
            radius = 30; % 调整这个值以控制锐化半径
            sharpFilter = ones(rows, cols);
            [X, Y] = meshgrid(1:cols, 1:rows);
            distance = sqrt((X - centerCol).^2 + (Y - centerRow).^2);
            sharpFilter(distance < radius) = 0.1; % 调整这个值以控制锐化程度
            sharpenedSpectrum = spectrum .* sharpFilter;
            % 恢复锐化后的图像
            app.OutImg = abs(ifft2(ifftshift(sharpenedSpectrum)))+double(grayImage);
            app.Label_2.Text = '锐化后图像';
            imshow(app.OutImg,'Parent',app.UIAxes_2,'DisplayRange',[]);
            app.UIAxes3.Title.String = '锐化后直方图';
            h = imhist(app.OutImg);
            bar(app.UIAxes3,h);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '图像处理系统';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '    文件    ';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Accelerator = 'N';
            app.Menu_2.Text = '打开';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Accelerator = 'E';
            app.Menu_4.Text = '另存为';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Accelerator = 'W';
            app.Menu_5.Text = '退出';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Accelerator = 'S';
            app.Menu_3.Text = '保存';
            app.Menu_6 = uimenu(app.UIFigure);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '  关于  ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像增强 ';
            app.Panel_10 = uipanel(app.Tab);
            app.Panel_10.Title = '图片加噪';
            app.GridLayout_9 = uigridlayout(app.Panel_10);
            app.Button_37 = uibutton(app.GridLayout_9, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_37.Text = '椒盐噪声';
            app.Button_38 = uibutton(app.GridLayout_9, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_38.Text = '高斯噪声';
            app.Button_39 = uibutton(app.GridLayout_9, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_39.Text = '乘性噪声';
            app.Button_40 = uibutton(app.GridLayout_9, 'push');
            app.Button_40.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_40.Text = '泊松噪声';
            app.TabGroup2 = uitabgroup(app.Tab);
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '平滑空间算法';
            app.Panel_14 = uipanel(app.Tab_6);
            app.Panel_14.Title = '平滑空间算法';
            app.GridLayout_13 = uigridlayout(app.Panel_14);
            app.Button_41 = uibutton(app.GridLayout_13, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '均值滤波';
            app.Button_42 = uibutton(app.GridLayout_13, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '中值滤波';
            app.Button_43 = uibutton(app.GridLayout_13, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '高斯滤波';
            app.EditField = uieditfield(app.GridLayout_13, 'numeric');
            app.EditField.Value = 5;
            app.EditField_2Label = uilabel(app.GridLayout_13);
            app.EditField_2Label.Text = '滤波器大小';
            app.EditField_2 = uieditfield(app.GridLayout_13, 'numeric');
            app.EditField_2.Value = 5;
            app.EditField_3Label = uilabel(app.GridLayout_13);
            app.EditField_3Label.Text = '滤波器大小';
            app.EditField_3 = uieditfield(app.GridLayout_13, 'numeric');
            app.EditField_3.Value = 5;
            app.Label_3 = uilabel(app.GridLayout_13);
            app.Label_3.Text = '方差大小';
            app.EditField_4 = uieditfield(app.GridLayout_13, 'numeric');
            app.EditField_4.Value = 5;
            app.EditField_2Label_2 = uilabel(app.GridLayout_13);
            app.EditField_2Label_2.Text = '滤波器大小';
            app.Tab_8 = uitab(app.TabGroup2);
            app.Tab_8.Title = '锐化和灰度变换';
            app.Panel_15 = uipanel(app.Tab_8);
            app.Panel_15.Title = '锐化';
            app.GridLayout_14 = uigridlayout(app.Panel_15);
            app.SobelButton = uibutton(app.GridLayout_14, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.LaplaceButton = uibutton(app.GridLayout_14, 'push');
            app.LaplaceButton.ButtonPushedFcn = createCallbackFcn(app, @LaplaceButtonPushed, true);
            app.LaplaceButton.Text = 'Laplace';
            app.Panel_16 = uipanel(app.Tab_8);
            app.Panel_16.Title = '灰度变换';
            app.GridLayout3 = uigridlayout(app.Panel_16);
            app.Button_44 = uibutton(app.GridLayout3, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '负片';
            app.Button_45 = uibutton(app.GridLayout3, 'push');
            app.Button_45.ButtonPushedFcn = createCallbackFcn(app, @Button_45Pushed, true);
            app.Button_45.Text = '伽马变换';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 2;
            app.Label_4 = uilabel(app.GridLayout3);
            app.Label_4.Text = '伽马参数';
            app.EditField_5 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_5.Value = 2;
            app.Tab_11 = uitab(app.TabGroup2);
            app.Tab_11.Title = '频域运算';
            app.Panel_20 = uipanel(app.Tab_11);
            app.Panel_20.Title = '频域运算';
            app.GridLayout7 = uigridlayout(app.Panel_20);
            app.Button_51 = uibutton(app.GridLayout7, 'push');
            app.Button_51.ButtonPushedFcn = createCallbackFcn(app, @Button_51Pushed, true);
            app.Button_51.Text = '傅里叶变换图';
            app.Button_52 = uibutton(app.GridLayout7, 'push');
            app.Button_52.ButtonPushedFcn = createCallbackFcn(app, @Button_52Pushed, true);
            app.Button_52.Text = '平滑';
            app.Button_53 = uibutton(app.GridLayout7, 'push');
            app.Button_53.ButtonPushedFcn = createCallbackFcn(app, @Button_53Pushed, true);
            app.Button_53.Text = '锐化';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '  图像分割';
            app.TabGroup3 = uitabgroup(app.Tab_2);
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '点、线、边缘的检测';
            app.Panel_13 = uipanel(app.Tab_9);
            app.Panel_13.Title = '边缘检测';
            app.GridLayout_12 = uigridlayout(app.Panel_13);
            app.RobertsButton = uibutton(app.GridLayout_12, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uibutton(app.GridLayout_12, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.PrewittButton = uibutton(app.GridLayout_12, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.LOGButton = uibutton(app.GridLayout_12, 'push');
            app.LOGButton.ButtonPushedFcn = createCallbackFcn(app, @LOGButtonPushed, true);
            app.LOGButton.Text = 'LOG';
            app.Panel_17 = uipanel(app.Tab_9);
            app.Panel_17.Title = '点检测';
            app.GridLayout4 = uigridlayout(app.Panel_17);
            app.HarrisButton = uibutton(app.GridLayout4, 'push');
            app.HarrisButton.ButtonPushedFcn = createCallbackFcn(app, @HarrisButtonPushed, true);
            app.HarrisButton.Text = 'Harris';
            app.FASTButton = uibutton(app.GridLayout4, 'push');
            app.FASTButton.ButtonPushedFcn = createCallbackFcn(app, @FASTButtonPushed, true);
            app.FASTButton.Text = 'FAST';
            app.Spinner = uispinner(app.GridLayout4);
            app.Spinner.Value = 50;
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = '特征点数量';
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '区域分割';
            app.Panel_7 = uipanel(app.Tab_10);
            app.Panel_7.Title = '区域分割';
            app.GridLayout6 = uigridlayout(app.Panel_7);
            app.GridLayout_6 = uigridlayout(app.GridLayout6);
            app.Button_25 = uibutton(app.GridLayout_6, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '阈值分割';
            app.Button_26 = uibutton(app.GridLayout_6, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '区域生长';
            app.Button_27 = uibutton(app.GridLayout_6, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '区域分离/聚合';
            app.Spinner_2 = uispinner(app.GridLayout_6);
            app.Spinner_2.Value = 127;
            app.Label_6 = uilabel(app.GridLayout_6);
            app.Label_6.Text = '阈值大小';
            app.Label_7 = uilabel(app.GridLayout_6);
            app.Label_7.Text = '阈值大小';
            app.Spinner_3 = uispinner(app.GridLayout_6);
            app.Spinner_3.Value = 127;
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '数字图像变换和形态学处理';
            app.Panel_8 = uipanel(app.Tab_3);
            app.Panel_8.Title = '数字图像变换';
            app.GridLayout_7 = uigridlayout(app.Panel_8);
            app.Button_29 = uibutton(app.GridLayout_7, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Text = '傅里叶变换';
            app.Button_30 = uibutton(app.GridLayout_7, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed, true);
            app.Button_30.Text = '傅里叶逆变换';
            app.Button_31 = uibutton(app.GridLayout_7, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '离散余弦变换';
            app.Button_32 = uibutton(app.GridLayout_7, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = '离散小波变换';
            app.Panel_19 = uipanel(app.Tab_3);
            app.Panel_19.Title = '形态学处理';
            app.GridLayout_15 = uigridlayout(app.Panel_19);
            app.Button_49 = uibutton(app.GridLayout_15, 'push');
            app.Button_49.ButtonPushedFcn = createCallbackFcn(app, @Button_49Pushed, true);
            app.Button_49.Text = '腐蚀';
            app.Button_50 = uibutton(app.GridLayout_15, 'push');
            app.Button_50.ButtonPushedFcn = createCallbackFcn(app, @Button_50Pushed, true);
            app.Button_50.Text = '膨胀';
            app.Button_47 = uibutton(app.GridLayout_15, 'push');
            app.Button_47.ButtonPushedFcn = createCallbackFcn(app, @Button_47Pushed, true);
            app.Button_47.Text = '开运算';
            app.Button_48 = uibutton(app.GridLayout_15, 'push');
            app.Button_48.ButtonPushedFcn = createCallbackFcn(app, @Button_48Pushed, true);
            app.Button_48.Text = '闭运算';
            app.Label_8 = uilabel(app.GridLayout_15);
            app.Label_8.Text = '卷积核大小';
            app.Slider_2 = uislider(app.GridLayout_15);
            app.Slider_2.Limits = [0 10];
            app.Slider_2.MajorTicks = [0 2 4 6 8 10];
            app.Slider_2.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.Slider_2.MinorTicks = [1 3 5 7 9];
            app.Slider_2.Value = 3;
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '直方图统计';
            app.Panel_5 = uipanel(app.Tab_5);
            app.Panel_5.Title = '直方图统计';
            app.GridLayout_5 = uigridlayout(app.Panel_5);
            app.Button_23 = uibutton(app.GridLayout_5, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '直方图';
            app.Button_24 = uibutton(app.GridLayout_5, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '均衡化';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = '图像基本操作';
            app.GridLayout2 = uigridlayout(app.Panel_6);
            app.Button_10 = uibutton(app.GridLayout2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Tooltip = {''};
            app.Button_10.Text = '灰度化';
            app.Button_11 = uibutton(app.GridLayout2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Tooltip = {''};
            app.Button_11.Text = '二值化';
            app.Button_12 = uibutton(app.GridLayout2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Tooltip = {''};
            app.Button_12.Text = '水平翻转';
            app.Button_13 = uibutton(app.GridLayout2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Tooltip = {''};
            app.Button_13.Text = '垂直翻转';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tooltip = {''};
            app.Button_2.Text = '清除';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'处理后图像变成原图像'};
            app.Button.Text = '图像转换';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '处理后图像';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图像';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '输入直方图')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '输出直方图')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, ' ')
            ylabel(app.UIAxes, ' ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, ' ')
            xlabel(app.UIAxes_2, ' ')
            ylabel(app.UIAxes_2, ' ')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end